classdef BehaviorBox_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        Curtain                         matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Folder                          matlab.ui.control.Button
        Git                             matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        EasyLvProb                      matlab.ui.control.NumericEditField
        EasyLvProbLabel                 matlab.ui.control.Label
        HighLvProb                      matlab.ui.control.NumericEditField
        HighLvProbEditFieldLabel        matlab.ui.control.Label
        HardLvList                      matlab.ui.control.EditField
        EasyLvList                      matlab.ui.control.EditField
        InputControlPanel               matlab.ui.container.Panel
        InputDelayRespond               matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        InputDelayStart                 matlab.ui.control.NumericEditField
        DuringTrialEditFieldLabel       matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        IntertrialMalCheckBox           matlab.ui.control.CheckBox
        FinishlineCheckBox              matlab.ui.control.CheckBox
        RoundUpEditField                matlab.ui.control.NumericEditField
        RoundUpEditFieldLabel           matlab.ui.control.Label
        RoundupchoiceCheckBox           matlab.ui.control.CheckBox
        TurnMagEditField                matlab.ui.control.NumericEditField
        TurnMagEditFieldLabel           matlab.ui.control.Label
        StimulusvariablesPanel          matlab.ui.container.Panel
        SegmentthicknessEditField       matlab.ui.control.NumericEditField
        SegmentthicknessEditFieldLabel  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        ReadyCuecolorLabel              matlab.ui.control.Label
        BackgroundEditField             matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel        matlab.ui.control.Label
        Stimulus_DimColor               matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel_2      matlab.ui.control.Label
        FlashColor                      matlab.ui.control.NumericEditField
        EditField_5Label_2              matlab.ui.control.Label
        LinebrightnessEditField         matlab.ui.control.NumericEditField
        LinebrightnessEditFieldLabel    matlab.ui.control.Label
        BetweenSpotlight                matlab.ui.control.NumericEditField
        BetweenSpotlight_textlabel      matlab.ui.control.Label
        SpotlightbrightnessEditField    matlab.ui.control.NumericEditField
        SpotlightbrightnessEditFieldLabel  matlab.ui.control.Label
        SegmentSpacingEditField         matlab.ui.control.NumericEditField
        SegmentSpacingEditFieldLabel    matlab.ui.control.Label
        SegmentlengthEditField          matlab.ui.control.NumericEditField
        SegmentlengthEditFieldLabel     matlab.ui.control.Label
        BoxNum                          matlab.ui.control.NumericEditField
        BoxEditFieldLabel               matlab.ui.control.Label
        edit22                          matlab.ui.control.EditField
        text72                          matlab.ui.control.Label
        edit17                          matlab.ui.control.EditField
        edit19                          matlab.ui.control.EditField
        edit20                          matlab.ui.control.EditField
        edit21                          matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        CorrectAngleAdj                 matlab.ui.control.NumericEditField
        CorrectionoffsetEditFieldLabel  matlab.ui.control.Label
        ContTol                         matlab.ui.control.NumericEditField
        ContangletolEditFieldLabel      matlab.ui.control.Label
        edit51_3                        matlab.ui.control.EditField
        edit51_2                        matlab.ui.control.EditField
        edit50_2                        matlab.ui.control.EditField
        edit51                          matlab.ui.control.EditField
        text122                         matlab.ui.control.Label
        edit50                          matlab.ui.control.EditField
        text121                         matlab.ui.control.Label
        checkbox19                      matlab.ui.control.CheckBox
        Stimulus_type                   matlab.ui.control.DropDown
        text65                          matlab.ui.control.Label
        SubjectPanel                    matlab.ui.container.Panel
        WeightgEditField                matlab.ui.control.EditField
        WeightgEditFieldLabel           matlab.ui.control.Label
        Strain                          matlab.ui.control.EditField
        text13                          matlab.ui.control.Label
        Inv                             matlab.ui.control.EditField
        Investigator                    matlab.ui.control.Label
        text86                          matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        SecBwPulse                      matlab.ui.control.NumericEditField
        secbwPulsesEditFieldLabel       matlab.ui.control.Label
        edit11_2                        matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        edit11                          matlab.ui.control.EditField
        checkbox2                       matlab.ui.control.CheckBox
        checkbox15                      matlab.ui.control.CheckBox
        RightPulse                      matlab.ui.control.NumericEditField
        Rrewardtime                     matlab.ui.control.EditField
        RButton_2                       matlab.ui.control.Button
        JuiceCheckBox                   matlab.ui.control.CheckBox
        LeftPulse                       matlab.ui.control.NumericEditField
        Lrewardtime                     matlab.ui.control.EditField
        LButton_2                       matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        edit15                          matlab.ui.control.EditField
        edit14                          matlab.ui.control.EditField
        text35                          matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        PersistincorrectCheckBox        matlab.ui.control.CheckBox
        EditField_4                     matlab.ui.control.NumericEditField
        PersistcorrectCheckBox          matlab.ui.control.CheckBox
        checkbox3                       matlab.ui.control.CheckBox
        SideBiassEditField              matlab.ui.control.NumericEditField
        SideBiassEditField_2Label       matlab.ui.control.Label
        edit10                          matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        HoldStill                       matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        edit8                           matlab.ui.control.EditField
        text10                          matlab.ui.control.Label
        edit6                           matlab.ui.control.EditField
        text8                           matlab.ui.control.Label
        Pokes_ignored_time              matlab.ui.control.EditField
        checkbox4                       matlab.ui.control.CheckBox
        uipanel1                        matlab.ui.container.Panel
        RampMin                         matlab.ui.control.NumericEditField
        RampMax                         matlab.ui.control.NumericEditField
        RampNum                         matlab.ui.control.NumericEditField
        RampCheckBox                    matlab.ui.control.CheckBox
        prob_list                       matlab.ui.control.TextArea
        checkbox22                      matlab.ui.control.CheckBox
        DistractorsSpinner              matlab.ui.control.Spinner
        DistractorsSpinnerLabel         matlab.ui.control.Label
        edit49                          matlab.ui.control.EditField
        text119                         matlab.ui.control.Label
        edit48                          matlab.ui.control.EditField
        text117                         matlab.ui.control.Label
        Stimulus_side                   matlab.ui.control.DropDown
        text89                          matlab.ui.control.Label
        Box_Input_type                  matlab.ui.control.DropDown
        text66                          matlab.ui.control.Label
        TemporaryTab                    matlab.ui.container.Tab
        ExpireAfterButtonGroup          matlab.ui.container.ButtonGroup
        OffButton                       matlab.ui.control.RadioButton
        TrialsRemainingLabel            matlab.ui.control.Label
        TrialsDuration                  matlab.ui.control.NumericEditField
        PerformanceThreshold            matlab.ui.control.NumericEditField
        TrialNumberButton               matlab.ui.control.RadioButton
        PerformanceButton               matlab.ui.control.RadioButton
        InputControlPanel_2             matlab.ui.container.Panel
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_6Label_2              matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        IntertrialMalCheckBox_2         matlab.ui.control.CheckBox
        uipanel3_2                      matlab.ui.container.Panel
        checkbox3_2                     matlab.ui.control.CheckBox
        HoldStill_2                     matlab.ui.control.NumericEditField
        EditField_5Label_3              matlab.ui.control.Label
        edit8_2                         matlab.ui.control.EditField
        text10_2                        matlab.ui.control.Label
        edit6_2                         matlab.ui.control.EditField
        text8_2                         matlab.ui.control.Label
        Pokes_ignored_time_2            matlab.ui.control.EditField
        checkbox4_2                     matlab.ui.control.CheckBox
        uipanel1_2                      matlab.ui.container.Panel
        prob_list_2                     matlab.ui.control.TextArea
        checkbox22_2                    matlab.ui.control.CheckBox
        DistractorsSpinner_2            matlab.ui.control.Spinner
        DistractorsSpinnerLabel_2       matlab.ui.control.Label
        NotesTab                        matlab.ui.container.Tab
        TextArea2                       matlab.ui.control.TextArea
        OutputTab                       matlab.ui.container.Tab
        MsgBox                          matlab.ui.control.TextArea
        PerformanceTab                  matlab.ui.container.Tab
        TimersTab                       matlab.ui.container.Tab
        AllTimeTab                      matlab.ui.container.Tab
        WeightWaterTab                  matlab.ui.container.Tab
        OtherUnusedTab                  matlab.ui.container.Tab
        ArduinosDropDown                matlab.ui.control.DropDown
        ArduinosDropDownLabel           matlab.ui.control.Label
        EquipmentPanel                  matlab.ui.container.Panel
        SaveComputerSettingsButton      matlab.ui.control.Button
        LoadComputerSettingsButton      matlab.ui.control.Button
        RESETALLButton_2                matlab.ui.control.StateButton
        OpenBothButton                  matlab.ui.control.StateButton
        OpenRButton                     matlab.ui.control.StateButton
        OpenLButton                     matlab.ui.control.StateButton
        checkbox8                       matlab.ui.control.CheckBox
        uipanel8                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        pushbutton3                     matlab.ui.control.Button
        FlashifwrongCheckBox            matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        checkbox6                       matlab.ui.control.CheckBox
        uipanel12                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        edit53                          matlab.ui.control.EditField
        text124                         matlab.ui.control.Label
        edit52                          matlab.ui.control.EditField
        text123                         matlab.ui.control.Label
        edit42                          matlab.ui.control.EditField
        text100                         matlab.ui.control.Label
        edit41                          matlab.ui.control.EditField
        text99                          matlab.ui.control.Label
        edit40                          matlab.ui.control.EditField
        text98                          matlab.ui.control.Label
        edit39                          matlab.ui.control.EditField
        text97                          matlab.ui.control.Label
        text96                          matlab.ui.control.Label
        edit38                          matlab.ui.control.EditField
        text95                          matlab.ui.control.Label
        edit37                          matlab.ui.control.EditField
        text94                          matlab.ui.control.Label
        edit35                          matlab.ui.control.EditField
        checkbox18                      matlab.ui.control.CheckBox
        edit23                          matlab.ui.control.EditField
        checkbox1                       matlab.ui.control.CheckBox
        popupmenu9                      matlab.ui.control.DropDown
        uipanel11                       matlab.ui.container.Panel
        edit32                          matlab.ui.control.EditField
        edit30                          matlab.ui.control.EditField
        edit29                          matlab.ui.control.EditField
        checkbox13                      matlab.ui.control.CheckBox
        edit28                          matlab.ui.control.EditField
        popupmenu4                      matlab.ui.control.DropDown
        edit26                          matlab.ui.control.EditField
        edit25                          matlab.ui.control.EditField
        pushbutton19                    matlab.ui.control.Button
        text85                          matlab.ui.control.Label
        text84                          matlab.ui.control.Label
        text83                          matlab.ui.control.Label
        text82                          matlab.ui.control.Label
        text81                          matlab.ui.control.Label
        text79                          matlab.ui.control.Label
        text17                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        Subject                         matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        Preview                         matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        stop                            matlab.ui.control.StateButton
        skip                            matlab.ui.control.StateButton
        pause                           matlab.ui.control.StateButton
        ff                              matlab.ui.control.StateButton
        pushbutton1                     matlab.ui.control.Button
        TestBoxButton                   matlab.ui.control.Button
        pushbutton4                     matlab.ui.control.Button
        text15                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        text18                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        text3                           matlab.ui.control.Label
        text2                           matlab.ui.control.Label
        text30                          matlab.ui.control.Label
        text25                          matlab.ui.control.Label
        text29                          matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text26                          matlab.ui.control.Label
        text28                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        text1                           matlab.ui.control.Label
    end

    properties (Access = public)
    end
    methods (Access = private)
        function PreviewStimulus(app, hObject, eventdata, handles)
            app.text1.Text = 'Previewing stimulus...';
            BB = evalin('base','BB');
            try
                BB.getGUI();
                BB.TestStimulus();
            catch err
                BB.unwrapError(err)
                BB.cleanUP;
            end
        end

        function FindArduino(app, handles)
            arguments
                app
                handles
            end
            if ~isempty(app.edit22.Value)
                return
            end
            sl = serialportlist("Available");
            if isempty(sl)
                sl = serialportlist("All");
            end
            sl(contains(sl, 'Bluetooth', IgnoreCase=true)) = [];
            sl(contains(sl, 'COM1', IgnoreCase=true)) = [];
            sl = sl(contains(sl, '/dev/ttyACM', IgnoreCase=true));
            app.ArduinosDropDown.Items = sl;
            COMSlist = sl;
            if ~isempty(app.edit22.Value)
                %Leave it
            elseif ~isempty(COMSlist)
                if ispc
                    COMSnum = COMSlist{1}(regexp(COMSlist{1}, '\d'));
                elseif ismac
                    whichPort = contains(sl, 'cu.usbmodem');
                    if sum(whichPort) == 1
                        COMSnum = sl(whichPort);
                    end
                elseif isunix
                    whichPort = contains(sl, '/dev/tty');
                    if sum(whichPort) == 1
                        wc = split(sl(whichPort), '/dev/tty');
                        COMSnum = wc(2);
                    else
                        wsl = sl(whichPort);
                        wc = split(wsl(1), '/dev/tty');
                        COMSnum = wc(2);
                    end
                end
                app.edit22.Value = COMSnum; drawnow;
            else
                app.text1.Text = 'No available COMs found, check USB connections or use Keyboard Input.' ;
            end
        end

        function changeGUIvalue(app, field_handle, change)
            Old_Entry=str2double(get(field_handle,'String'));
            New_Entry=Old_Entry+change;
            if New_Entry<0
                New_Entry=0;
            end
            set(field_handle,'String',num2str(New_Entry));
        end

        function loadGuiInputAsStruct(app, handles, ~)
            try
                BB = evalin("base", "BB");
            catch
                app.BehaviorBox_OpeningFcn
                BB = evalin("base", "BB");
            end
            %Read settings from interface:
            Sub = {app.Subject.Value};
            Invest = app.Inv.Value;
            Inp = app.Box_Input_type.Value;
            Str = app.Strain.Value;
            BBData = BehaviorBoxData("Inv", Invest, "Inp", Inp, "Sub",Sub, find=1);
            if isempty(handles.Strain.String) || all(handles.Strain.String ~= string(BBData.Str))
                handles.Strain.String = BBData.Str; drawnow;
            end
            assignin("base", "BBData", BBData)
            try
                if isempty(BBData.fds) || numel(BBData.fds.Files) == 0
                    disp('No saved settings found for current subject, using current values...');
                    return
                else
                    filename = BBData.fds.Files{end};
                    tree = split(filename, filesep);
                end
                Set = BBData.loadedData{end,3}.Settings;
                Set = Set(end);
                handles.Strain.String = Set.Strain;
                BBData.Str = Set.Strain;
            catch
                msg = 'Problem loading, check files... using current values';
                disp(msg);
                app.text1.Text = msg;
            end
            try
                skipnames = {'Strain', 'Investigator', 'Weight','Subject','Arduino_Com','BoxNum','Box_Input_type','prob_list'};
                names = fieldnames(Set);
                names(matches(names, skipnames)) = [];
                for s = names'
                    try
                        handles.(s{:}).Value = Set.(s{:});
                    catch err
                        sprintf("Problem: "+s{:})
                    end
                end
            catch err
                disp(err.message)
            end
            try
                BBData.AnalyzeAllData();
                LevOptions = "0.15, 1; 0.85, "+BBData.MaxLevel;
                app.prob_list.Value = LevOptions; drawnow
                Ax = BBData.plotLvByDayOneAxis(LevDay=0, Sc=1, Training=1);
                Ax.PickableParts = "none";
                clo(app.AllTimeTab)
                copyobj(Ax, app.AllTimeTab)
                close(Ax.Parent.Parent)
            end
            try
                t = BBData.PlotWeightAndWater();
                clo(app.WeightWaterTab)
                copyobj(t, app.WeightWaterTab)
                close(t.Parent)
            end
            app.WeightgEditField.Value = '';
            BB.getGUI();
            BB.Data_Object = BBData;
            text = "Settings synced.";
            fprintf('%s\n',text)
            app.text1.Text = text;
        end

        function LoadComputerSpecifics(app)
            % if ispc
            %     loadDir = fullfile(getenv('USERPROFILE'), 'Desktop', 'BehaviorBox');
            % elseif ismac
            %     loadDir = '/Users/willsnyder/Dropbox (Dropbox @RU)/Dropbox (Dropbox @RU)/Gilbert Lab/BehaviorBoxData/Data/';
            % elseif isunix
            %     loadDir = fullfile(getenv('HOME'), 'Desktop', 'BehaviorBox');
            % end
            loadDir = GetFilePath("Computer");
            saveFiles = dir(loadDir);
            COM = app.edit22.Value;
            wF = saveFiles(contains({saveFiles.name}, ['ComputerSettings' COM]));
            if isempty(wF) || numel(wF) > 1
                disp("Please check the Arduino Port and try again.")
                return
            end
            load(fullfile(wF.folder, wF.name), 'SavedComputerSpecifics')
            for f = fieldnames(SavedComputerSpecifics)'
                app.(f{:}).Value = SavedComputerSpecifics.(f{:});
            end
        end

        function SaveComputerSpecifics(app)
            %Settings will be specific to the COM number
            props = properties(app);
            names = cellfun(@(x)app.(x).Tag, properties(app), 'UniformOutput', false);
            boxstuff = props(contains(names, 'box_','IgnoreCase',true));
            stimstuff = props(contains(names, 'stimulus_','IgnoreCase',true));
            allstuff = [boxstuff' stimstuff'];
            SavedComputerSpecifics = struct();
            for i = allstuff
                SavedComputerSpecifics.(i{:}) = app.(i{:}).Value;
            end
            SavedComputerSpecifics.edit22 = app.edit22.Value;
            COM = app.edit22.Value;
            %SavedComputerSpecifics = struct();
            if isempty(COM)
                fprintf('Please use the Device Manager to find the COM port of the Arduino to proceed.\n');
                return
            end
            saveasname = fullfile(GetFilePath("Computer"), "ComputerSettings"+COM);
            save(saveasname, 'SavedComputerSpecifics')
            app.text1.Text = 'Computer settings saved.';
            fprintf('Computer settings saved.\n')
        end
        
        function printHardwareconnections(~, handles)
            current_selection_stimulus=get(handles.Box_Input_type,'Value');
            Two_ports=get(handles.Box_Two_ports,'Value');
            Two_puffs = 0;
            Air_Puff_Penalty = 1;
            fprintf('------SET UP-------\n')
            switch current_selection_stimulus
                case 1
                    fprintf('Contour crude stimulus\n')
                case 2
                    fprintf('Contour fine stimulus\n')
                case 3
                    fprintf('Square Vs Open Box crude stimulus\n')
                case 4
                    fprintf('Square Vs Open Box fine stimulus\n')
                case 5
                    fprintf('Match Target stimulus\n')
                case 11
                    fprintf('Contour Density: Identify the dashed-line contour\n')
            end
            current_selection_input=get(handles.Box_Input_type,'Value');
            switch current_selection_input
                case 1
                    fprintf('One Lever Input\n')
                    fprintf('- connect One Lever Input to Arduino pin analog 2\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 2
                    fprintf('Two Lever Input\n')
                    fprintf('- connect Left Lever Input to Arduino pin D2\n')
                    fprintf('- connect Right Lever Input to Arduino pin D7\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 3
                    fprintf('Three Pokes Input\n')
                    fprintf('- connect Center Poke Input to Arduino pin D3\n')
                    fprintf('- connect Left Poke Input to Arduino pin D2\n')
                    fprintf('- connect Right Poke Input to Arduino pin D7\n')
                    if Two_ports ==0
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    if Air_Puff_Penalty == 1
                        if Two_puffs == 0
                            fprintf('- connect puff valve output to Arduino pin D11\n')
                        else
                            fprintf('- connect left puff valve output to Arduino pin D11\n')
                            fprintf('- connect right puff valve output to Arduino pin D12\n')
                        end
                    end
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 4
                    fprintf('Rotating Ball Input\n')
                    fprintf('- connect Left Turn Input to Arduino pin D2\n')
                    fprintf('- connect Right Turn Input to Arduino pin D7\n')
                    if ~Two_ports
                        fprintf('- connect reward valve output to Arduino pin D8\n')
                    else
                        fprintf('- connect left reward valve output to Arduino pin D8\n')
                        fprintf('- connect right reward valve output to Arduino pin D6\n')
                    end
                    fprintf('- connect reset output to Arduino pin D4\n')
                    fprintf('- (optional) connect start trial LED output to Arduino pin D9\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
                case 5
                    fprintf('Lick Sensor Input\n')
                    fprintf('- connect Center Lick Sensor Input to Arduino pin D3\n')
                    fprintf('- connect Left Lick Sensor Input to Arduino pin D2\n')
                    fprintf('- connect Right Lick Sensor Input to Arduino pin D7\n')
                    fprintf('- connect Left water reward valve to Arduino pin D8\n')
                    fprintf('- connect Right water reward valve to Arduino pin D6\n')
                    fprintf('- connect AirPuff penalty valve to Arduino pin D11\n')
                case 6
                    fprintf('Wheel Input: Accu-Coder Rotary Encoder \n')
                    fprintf('- connect Encoder Power (white wire) to Arduino +5V \n')
                    fprintf('- connect Encoder Ground (black wire) to Arduino GND \n')
                    fprintf('- connect A (brown wire) to Arduino pin D2 \n')
                    fprintf('- connect B (red wire) to Arduino pin D3 \n')
                    fprintf('- connect Water reward valve to Arduino pin D6\n')
                    fprintf('- (optional) connect trigger input to pin D5, tick "ext trigger"\n')
            end
            fprintf('------------------\n');
        end
        function printWelcomeMsg(~)
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
            fprintf("888888b.            888                        d8b                  888888b.                     ");
            fprintf('\n');
            fprintf('888  "88b           888                        Y8P                  888  "88b                    ');
            fprintf('\n');
            fprintf("888  .88P           888                                             888  .88P                    ");
            fprintf('\n');
            fprintf("8888888K.   .d88b.  88888b.   8888b.  888  888 888  .d88b.  888d888 8888888K.   .d88b.  888  888 ");
            fprintf('\n');
            fprintf('888  "Y88b d8P  Y8b 888 "88b     "88b 888  888 888 d88""88b 888P"   888  "Y88b d88""88b `Y8bd8P');
            fprintf('\n');
            fprintf('888    888 88888888 888  888 .d888888 Y88  88P 888 888  888 888     888    888 888  888   X88K   ');
            fprintf('\n');
            fprintf('888   d88P Y8b.     888  888 888  888  Y8bd8P  888 Y88..88P 888     888   d88P Y88..88P .d8""8b. ');
            fprintf('\n');
            fprintf('8888888P"   "Y8888  888  888 "Y888888   Y88P   888  "Y88P"  888     8888888P"   "Y88P"  888  888 ');
            fprintf('\n');
            fprintf('_________________________________________________________________________________________________')
            fprintf('\n');
        end
        
        function results = LvProbChange(~, In)
            newvalue = 1-In;
            results = newvalue;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function BehaviorBox_OpeningFcn(app)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            %OPENING FUNCTION
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to BehaviorBox (see VARARGIN)
            handles.output = hObject;
            % **************** ADD THIS SECTION ******************
            %from: https://www.mathworks.com/matlabcentral/answers/482510-annotationpane-handle-appearing-in-guide-guis-with-panel-axes-in-r2019b
            % Check if scribeOverlay is a field and that it contains an annotation pane
            if isfield(handles,'scribeOverlay') && isa(handles.scribeOverlay(1),'matlab.graphics.shape.internal.AnnotationPane')
                delete(handles.scribeOverlay);
                handles = rmfield(handles, 'scribeOverlay');
            end
            % **********************  END ************************
            % Update handles structure
            guidata(hObject, handles);
            printWelcomeMsg(app)
            cd(GetFilePath("Computer")) %Change to app's folder on Desktop
            % Make Diary to log stdout:
            diary off
            app.MsgBox.Value = ''; drawnow
            try
                delete('BBAppoutput.txt');
            end
            diary('BBAppoutput.txt');
            sl = serialportlist("Available");
            if ~isempty(app.edit22.Value) && ~contains(app.edit22.Value, 'ACM', IgnoreCase=true) | ~isnumeric(app.edit22.Value)
               app.edit22.Value = '';
            end
            app.FindArduino(handles);
            LoadComputerSpecifics(app)
            printHardwareconnections(app, handles) %Print out welcome message and some instructions
            app.MsgBox.Value = fileread('BBAppoutput.txt');
            app.text1.Text = 'Setting up...'; drawnow;
            if app.Box_Input_type.Value == "Wheel"
                BB = BehaviorBoxWheel(handles, app);
                if app.RESETALLButton_2.Value
                    BB.ConfigureArduino("Rebuild",false);
                else
                    BB.ConfigureArduino();
                end
            else
                BB = BehaviorBoxNose(handles, app);
                if app.RESETALLButton_2.Value
                    BB.ConfigureArduino("Rebuild",false);
                else
                    BB.ConfigureArduino();
                end
            end
            try
                assignin("base", "BB", BB)
            end
            if app.Subject.Value ~= "w" %Look up subject if one is filled in
                loadGuiInputAsStruct(app, handles, 0)
            end
        % Clean Up:
            app.text1.Text = 'Ready';
            fprintf('Ready\n');
            diary off
            app.MsgBox.Value = fileread('BBAppoutput.txt'); drawnow;
            BB.toggleButtonsOnOff(BB.Buttons, 1)
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %run regular trials
            %START BUTTON
            try
                evalin('base','clc')
                if app.Box_Input_type.Value == "NosePoke"
                    trialObject = evalin('base','BB');
                elseif app.Box_Input_type.Value == "Wheel"
                    trialObject = evalin('base','BB');
                else
                    trialObject = evalin('base','BB');
                end
                trialObject.RunTrials();
                SaveComputerSpecifics(app);
            catch err
                disp(err.message);
                trialObject.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %TRAINING BUTTON
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %run training
            try
                %Constructor functions, SetUp arduino and variables
                ThisTrial = BehaviorBoxSuper(handles, app);
                %Start the training
                ThisTrial.RunTraining();
            catch err
                disp(err.message);
                ThisTrial.cleanUP();
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            %PRINT CONNECTION AND PINS NEEDED
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            printHardwareconnections(app, handles)
        end

        % Button pushed function: Preview
        function Preview_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % show PREVIEW STIMULUS BUTTON
            % hObject    handle to pushbutton10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            PreviewStimulus(app, hObject, eventdata, handles);
        end

        % Button pushed function: LoadButton
        function LoadButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % LOAD SUBJECT SETTINGS
            % hObject    handle to pushbutton18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            loadGuiInputAsStruct(app, handles, 0);
        end

        % Button pushed function: pushbutton19
        function pushbutton19_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % ORIENTATION TUNING ONLY
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                %run orientationTuning
                ThisTrial = BehaviorBoxSub2(handles);
                ThisTrial.RunOrientationTuning();
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: TestBoxButton
        function TestBoxButtonPushed(app, event)
            %To test water output:
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            % TEST BOX
            try
                if ismember('BB', evalin('base','who'))
                    BB = evalin('base','BB');
                    BB.TextBox();
                else
                    BehaviorBox_OpeningFcn(app)
                    BB.TextBox();
                end
                SaveComputerSpecifics(app)
            catch err
                disp(err.message);
                set(handles.text1,'String',err.message );
            end
        end

        % Button pushed function: LoadComputerSettingsButton
        function LoadComputerSettingsButtonPushed(app, event)
            % LOAD COMPUTER SETTINGS
            %To load the computer specific settings (such as window size
            %and position and water drop sizes)
            LoadComputerSpecifics(app);
        end

        % Button pushed function: SaveComputerSettingsButton
        function SaveComputerSettingsButtonPushed(app, event)
            % SAVE COMPUTER SETTINGS
            %To save the computer specific settings (such as window size
            %and position and water drop sizes) to a folder on the
            %desktop
            SaveComputerSpecifics(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            evalin('base', 'clear a BB BBSuper');
            delete(findobj("Type", "figure", "Name", "Stimulus"))
            %delete(findobj("Type", "figure", "Name", "Graphs"))
        end

        % Button pushed function: LButton_2
        function LButton_2Pushed(app, event)
            % L TEST WATER BUTTON
            a = evalin('base', 'BB.a');
            Valve = 'D8'; %Left
            Time = str2double(app.Lrewardtime.Value);
            Rep = app.LeftPulse.Value;
            % command to pulse valve
            for i = 1:Rep
                a.writeDigitalPin(Valve,1)
                pause(Time);
                a.writeDigitalPin(Valve,0)
                if i<Rep
                    pause(app.SecBwPulse.Value)
                end
            end
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            % R TEST WATER BUTTON
            a = evalin('base', 'BB.a');
            Valve = 'D6'; %Right
            Time = str2double(app.Rrewardtime.Value);
            % command to pulse valve
            for i = 1:app.RightPulse.Value
                a.writeDigitalPin(Valve,1)
                pause(Time);
                a.writeDigitalPin(Valve,0)
                if i<app.RightPulse.Value
                    pause(app.SecBwPulse.Value)
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %RESET BUTTON
            evalin('base', 'clear hidden; clc')
            evalin('base', 'clear a BB BBSuper');  drawnow;
            BehaviorBox_OpeningFcn(app)
        end

        % Value changed function: OpenLButton
        function OpenLButtonValueChanged(app, event)
            value = app.OpenLButton.Value;
            a = evalin('base', 'BB.a');
            Valve = 'D8'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
        end

        % Value changed function: OpenRButton
        function OpenRButtonValueChanged(app, event)
            value = app.OpenRButton.Value;
            a = evalin('base', 'BB.a');
            Valve = 'D6'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
        end

        % Value changed function: OpenBothButton
        function OpenBothButtonValueChanged(app, event)
            value = app.OpenBothButton.Value;
            a = evalin('base', 'BB.a');
            Valve = 'D8'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
            Valve = 'D6'; %Left
            a.writeDigitalPin(Valve,value); drawnow;
        end

        % Value changed function: RESETALLButton_2
        function RESETALLButtonPushed(app, event)
            app.RESETALLButton_2.Text = 'Resetting...';
            evalin('base', 'clear all hidden classes; clc'); drawnow;
            try
                BehaviorBox_OpeningFcn(app)
            end
            app.RESETALLButton_2.Value = 0;
            app.RESETALLButton_2.Text = 'Reset ALL!'; drawnow;
        end

        % Value changed function: Subject
        function SubjectValueChanged(app, event)
            value = app.Subject.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            loadGuiInputAsStruct(app, handles, 0);
        end

        % Button pushed function: Git
        function GitButtonPushed(app, event)
            evalin("base", "!git stash && git pull")
        end

        % Button pushed function: Folder
        function FolderButtonPushed(app, event)
            try
                BB = evalin("base", "BB");
                winopen(BB.Data_Object.filedir)
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
                viewDualCameras();
            end
        end

        % Button pushed function: Curtain
        function CurtainButtonPushed(app, event)
            try
                Stim = BehaviorBoxVisualStimulus();
                Stim.CurtainOn();
            end
        end

        % Value changed function: EasyLvProb
        function EasyLvProbValueChanged(app, event)
            app.HighLvProb.Value = 1-app.EasyLvProb.Value;
        end

        % Value changed function: HighLvProb
        function HighLvProbValueChanged(app, event)
            app.EasyLvProb.Value = 1-app.HighLvProb.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'colorcube');
            app.figure1.Name = 'BehaviorBox';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'idle';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Left';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Right ';
            app.text28 = uilabel(app.figure1);
            app.text28.Tag = 'text28';
            app.text28.Text = '-';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = '-';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Rewards';
            app.text29 = uilabel(app.figure1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'total correct:';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = '0';
            app.text30 = uilabel(app.figure1);
            app.text30.Tag = 'text30';
            app.text30.Text = '--';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Time(min):';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = '-';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'choices';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'difficulty';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = '0';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Trial Number';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'PrintSetup';
            app.pushbutton4.Tooltip = 'show all connections required in command window';
            app.pushbutton4.Text = 'Setup';
            app.TestBoxButton = uibutton(app.figure1, 'push');
            app.TestBoxButton.ButtonPushedFcn = createCallbackFcn(app, @TestBoxButtonPushed, true);
            app.TestBoxButton.Tag = 'Water';
            app.TestBoxButton.Text = 'Test Box';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'Start';
            app.pushbutton1.Tooltip = 'Start the complete training';
            app.pushbutton1.Text = 'Start';
            app.ff = uibutton(app.figure1, 'state');
            app.ff.Tag = 'FastForward';
            app.ff.Tooltip = {'Click this button to fast-forward through the current pause interval'};
            app.ff.Icon = fullfile(pathToMLAPP, 'imgs', 'Forward_font_awesome.svg.png');
            app.ff.Text = '';
            app.pause = uibutton(app.figure1, 'state');
            app.pause.Tag = 'Pause';
            app.pause.Icon = fullfile(pathToMLAPP, 'imgs', 'pause.png');
            app.pause.Text = '';
            app.skip = uibutton(app.figure1, 'state');
            app.skip.Tag = 'Skip';
            app.skip.Icon = fullfile(pathToMLAPP, 'imgs', 'skip.png');
            app.skip.Text = '';
            app.stop = uibutton(app.figure1, 'state');
            app.stop.Tag = 'Stop';
            app.stop.Tooltip = 'Stop all';
            app.stop.Text = 'Stop';
            app.ResetButton = uibutton(app.figure1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tag = 'ResetButton';
            app.ResetButton.Tooltip = {'Click to clear memory and reconnect Arduino.'};
            app.ResetButton.Text = 'Reset';
            app.Preview = uibutton(app.figure1, 'push');
            app.Preview.ButtonPushedFcn = createCallbackFcn(app, @Preview_Callback, true);
            app.Preview.Tag = 'ShowStim';
            app.Preview.Tooltip = {'Press to generate an example of the current level. May crash if the flash routine hasn''t finished.'};
            app.Preview.Text = 'Preview';
            app.LoadButton = uibutton(app.figure1, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_Callback, true);
            app.LoadButton.Tag = 'Subject';
            app.LoadButton.Tooltip = {'Open mouse''s folder'};
            app.LoadButton.Text = 'Subject:';
            app.Subject = uieditfield(app.figure1, 'text');
            app.Subject.ValueChangedFcn = createCallbackFcn(app, @SubjectValueChanged, true);
            app.Subject.Tag = 'Subject';
            app.Subject.Tooltip = 'Enter animal number here';
            app.Subject.Value = 'w';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = '0';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = '0';
            app.TabGroup = uitabgroup(app.figure1);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.uipanel1 = uipanel(app.SettingsTab);
            app.uipanel1.Title = 'Level & Side';
            app.uipanel1.Tag = 'uipanel1';
            app.text66 = uilabel(app.uipanel1);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Input:';
            app.Box_Input_type = uidropdown(app.uipanel1);
            app.Box_Input_type.Items = {'One Lever', 'Two Levers', 'NosePoke', 'Rotating Ball', 'Lick Sensor', 'Wheel', 'Lick Go/No-Go', 'Keyboard'};
            app.Box_Input_type.Tag = 'Box_Input_type';
            app.Box_Input_type.Tooltip = 'choose input';
            app.Box_Input_type.Value = 'NosePoke';
            app.text89 = uilabel(app.uipanel1);
            app.text89.Tag = 'text89';
            app.text89.Text = 'Side:';
            app.Stimulus_side = uidropdown(app.uipanel1);
            app.Stimulus_side.Items = {'Random', 'Left only', 'Right only', 'Alternate repeat', 'Alternate random', 'Side-Bias Correction', 'Pseudo-Random', 'Keyboard'};
            app.Stimulus_side.Tag = 'Stimulus_side';
            app.Stimulus_side.Tooltip = 'choose stimulus';
            app.Stimulus_side.Value = 'Random';
            app.text117 = uilabel(app.uipanel1);
            app.text117.Tag = 'text117';
            app.text117.Text = 'Alt. Random:';
            app.edit48 = uieditfield(app.uipanel1, 'text');
            app.edit48.Tag = 'MinRandAlt';
            app.edit48.Tooltip = 'What the starting opacity is in complete training';
            app.edit48.Value = '2';
            app.text119 = uilabel(app.uipanel1);
            app.text119.Tag = 'text119';
            app.text119.Text = '<';
            app.edit49 = uieditfield(app.uipanel1, 'text');
            app.edit49.Tag = 'MaxRandAlt';
            app.edit49.Tooltip = 'What the starting opacity is in complete training';
            app.edit49.Value = '4';
            app.DistractorsSpinnerLabel = uilabel(app.uipanel1);
            app.DistractorsSpinnerLabel.Text = 'Level';
            app.DistractorsSpinner = uispinner(app.uipanel1);
            app.DistractorsSpinner.Limits = [1 20];
            app.DistractorsSpinner.Tag = 'Starting_opacity';
            app.DistractorsSpinner.Value = 1;
            app.checkbox22 = uicheckbox(app.uipanel1);
            app.checkbox22.Tag = 'EasyTrials';
            app.checkbox22.Tooltip = 'Defines if easier trials should be shown or not';
            app.checkbox22.Text = 'Easy %';
            app.checkbox22.Value = true;
            app.prob_list = uitextarea(app.uipanel1);
            app.prob_list.Tag = 'prob_list';
            app.RampCheckBox = uicheckbox(app.uipanel1);
            app.RampCheckBox.Tag = 'Ramp';
            app.RampCheckBox.Text = 'Ramp';
            app.RampNum = uieditfield(app.uipanel1, 'numeric');
            app.RampNum.Tag = 'RampNum';
            app.RampNum.Tooltip = {'How many trials at each Level in the Ramp?'};
            app.RampMax = uieditfield(app.uipanel1, 'numeric');
            app.RampMax.Tag = 'RampMaxLevel';
            app.RampMax.Tooltip = {'Maximum Level'};
            app.RampMin = uieditfield(app.uipanel1, 'numeric');
            app.RampMin.Tag = 'RampMinLevel';
            app.RampMin.Tooltip = {'Minimum Level'};
            app.uipanel3 = uipanel(app.SettingsTab);
            app.uipanel3.Title = 'Trial Intervals';
            app.uipanel3.Tag = 'uipanel3';
            app.checkbox4 = uicheckbox(app.uipanel3);
            app.checkbox4.Tag = 'Input_ignored';
            app.checkbox4.Text = 'Input ignored';
            app.Pokes_ignored_time = uieditfield(app.uipanel3, 'text');
            app.Pokes_ignored_time.Tag = 'Pokes_ignored_time';
            app.Pokes_ignored_time.Tooltip = 'ignore all input for how many seconds after trial begin';
            app.Pokes_ignored_time.Value = '2';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Penalty(s)';
            app.edit6 = uieditfield(app.uipanel3, 'text');
            app.edit6.Tag = 'Penalty_time';
            app.edit6.Tooltip = 'penalty delay in seconds';
            app.edit6.Value = '1';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Intertrial (s)';
            app.edit8 = uieditfield(app.uipanel3, 'text');
            app.edit8.Tag = 'Intertrial_time';
            app.edit8.Tooltip = 'Interval between each trial in seconds';
            app.edit8.Value = '1';
            app.EditField_5Label = uilabel(app.uipanel3);
            app.EditField_5Label.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.EditField_5Label.Text = 'Hold still (s):';
            app.HoldStill = uieditfield(app.uipanel3, 'numeric');
            app.HoldStill.Limits = [0 10];
            app.HoldStill.Tag = 'HoldStill';
            app.HoldStill.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.text12 = uilabel(app.uipanel3);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Timeout (s)';
            app.edit10 = uieditfield(app.uipanel3, 'text');
            app.edit10.Tag = 'Box_Timeout_after_time';
            app.edit10.Tooltip = 'trial times out and next one begins after how many seconds';
            app.edit10.Value = '0';
            app.SideBiassEditField_2Label = uilabel(app.uipanel3);
            app.SideBiassEditField_2Label.Text = 'SideBias (s):';
            app.SideBiassEditField = uieditfield(app.uipanel3, 'numeric');
            app.SideBiassEditField.Limits = [0.05 0.5];
            app.SideBiassEditField.Tag = 'SideBiasInterval';
            app.SideBiassEditField.Value = 0.25;
            app.checkbox3 = uicheckbox(app.uipanel3);
            app.checkbox3.Tag = 'Repeat_wrong';
            app.checkbox3.Tooltip = 'repeat the same side if last one was wrong';
            app.checkbox3.Text = 'repeat wrong';
            app.PersistcorrectCheckBox = uicheckbox(app.uipanel3);
            app.PersistcorrectCheckBox.Tag = 'Stimulus_PersistCorrect';
            app.PersistcorrectCheckBox.Text = 'Persist correct?';
            app.PersistcorrectCheckBox.Value = true;
            app.EditField_4 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_4.Limits = [0 60];
            app.EditField_4.Tag = 'Stimulus_PersistCorrectInterv';
            app.EditField_4.Value = 1;
            app.PersistincorrectCheckBox = uicheckbox(app.uipanel3);
            app.PersistincorrectCheckBox.Tag = 'Stimulus_PersistIncorrect';
            app.PersistincorrectCheckBox.Text = 'Persist incorrect?';
            app.EditField_3 = uieditfield(app.uipanel3, 'numeric');
            app.EditField_3.Limits = [0 60];
            app.EditField_3.Tag = 'Stimulus_PersistIncorrectInterv';
            app.EditField_3.Value = 1;
            app.text35 = uilabel(app.uipanel3);
            app.text35.Tag = 'text35';
            app.text35.Text = 'binsize:';
            app.edit14 = uieditfield(app.uipanel3, 'text');
            app.edit14.Tag = 'Data_Sbin';
            app.edit14.Tooltip = {'How many trials in a small bin'};
            app.edit14.Value = '10';
            app.edit15 = uieditfield(app.uipanel3, 'text');
            app.edit15.Tag = 'Data_Lbin';
            app.edit15.Tooltip = {'How many small bins in a full bin'};
            app.edit15.Value = '2';
            app.uipanel4 = uipanel(app.SettingsTab);
            app.uipanel4.Title = 'Reward / Penalty';
            app.uipanel4.Tag = 'uipanel4';
            app.LButton_2 = uibutton(app.uipanel4, 'push');
            app.LButton_2.ButtonPushedFcn = createCallbackFcn(app, @LButton_2Pushed, true);
            app.LButton_2.Tag = 'LeftValveButton';
            app.LButton_2.Tooltip = {'Press to test Left water reward size'};
            app.LButton_2.Text = 'L';
            app.Lrewardtime = uieditfield(app.uipanel4, 'text');
            app.Lrewardtime.Tag = 'Box_Lrewardtime';
            app.Lrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Lrewardtime.Value = '0.04';
            app.LeftPulse = uieditfield(app.uipanel4, 'numeric');
            app.LeftPulse.Limits = [1 10];
            app.LeftPulse.Tag = 'Box_LeftPulse';
            app.LeftPulse.Tooltip = {'How many pulses from the left water valve'};
            app.LeftPulse.Value = 1;
            app.JuiceCheckBox = uicheckbox(app.uipanel4);
            app.JuiceCheckBox.Tag = 'Box_Juice';
            app.JuiceCheckBox.Tooltip = {'Check this box if the mice are getting fruit juice instead of water.'};
            app.JuiceCheckBox.Text = 'Juice?';
            app.RButton_2 = uibutton(app.uipanel4, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Tag = 'RightValveButton';
            app.RButton_2.Tooltip = {'Press to test Right water reward size'};
            app.RButton_2.Text = 'R';
            app.Rrewardtime = uieditfield(app.uipanel4, 'text');
            app.Rrewardtime.Tag = 'Box_Rrewardtime';
            app.Rrewardtime.Tooltip = 'How long the vale is open in seconds';
            app.Rrewardtime.Value = '0.04';
            app.RightPulse = uieditfield(app.uipanel4, 'numeric');
            app.RightPulse.Limits = [1 10];
            app.RightPulse.Tag = 'Box_RightPulse';
            app.RightPulse.Tooltip = {'How many pulses from the rightwater valve'};
            app.RightPulse.Value = 1;
            app.checkbox15 = uicheckbox(app.uipanel4);
            app.checkbox15.Tag = 'Box_Two_ports';
            app.checkbox15.Tooltip = 'use pulses instead of single reward';
            app.checkbox15.Text = '2 valves';
            app.checkbox15.Value = true;
            app.checkbox2 = uicheckbox(app.uipanel4);
            app.checkbox2.Tag = 'Box_Variable_pulses';
            app.checkbox2.Tooltip = {'If selected a random # of pulses between the bounds will be given rather than the same fixed amount each time.'};
            app.checkbox2.Text = 'Variable?';
            app.edit11 = uieditfield(app.uipanel4, 'text');
            app.edit11.Tag = 'Box_Pulse_Min';
            app.edit11.Tooltip = {'Starting number of pulses'};
            app.edit11.Value = '1';
            app.Label = uilabel(app.uipanel4);
            app.Label.Text = '<';
            app.edit11_2 = uieditfield(app.uipanel4, 'text');
            app.edit11_2.Tag = 'Box_PulseMax';
            app.edit11_2.Tooltip = {'Maximum number of pulses'};
            app.edit11_2.Value = '1';
            app.secbwPulsesEditFieldLabel = uilabel(app.uipanel4);
            app.secbwPulsesEditFieldLabel.Text = 'sec b/w Pulses';
            app.SecBwPulse = uieditfield(app.uipanel4, 'numeric');
            app.SecBwPulse.Tag = 'Box_SecBwPulse';
            app.SecBwPulse.Value = 0.2;
            app.text86 = uilabel(app.SettingsTab);
            app.text86.Tag = 'text86';
            app.text86.Text = 'BehaviorBox App v4.7 - WBS 3/23';
            app.SubjectPanel = uipanel(app.SettingsTab);
            app.SubjectPanel.Title = 'Subject';
            app.Investigator = uilabel(app.SubjectPanel);
            app.Investigator.Tag = 'text128';
            app.Investigator.Text = 'Investigator';
            app.Inv = uieditfield(app.SubjectPanel, 'text');
            app.Inv.Tag = 'Investigator';
            app.Inv.Tooltip = 'Name of person conducting the training';
            app.Inv.Value = 'Will';
            app.text13 = uilabel(app.SubjectPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Strain';
            app.Strain = uieditfield(app.SubjectPanel, 'text');
            app.Strain.Tag = 'Strain';
            app.Strain.Tooltip = 'Enter animal strain e.g. Shank3, 16b';
            app.Strain.Value = 'w';
            app.WeightgEditFieldLabel = uilabel(app.SubjectPanel);
            app.WeightgEditFieldLabel.Text = 'Weight';
            app.WeightgEditField = uieditfield(app.SubjectPanel, 'text');
            app.WeightgEditField.Tag = 'Weight';
            app.WeightgEditField.Tooltip = {'Enter the animal''s weight in grams'};
            app.Panel_2 = uipanel(app.SettingsTab);
            app.Panel_2.Title = 'Panel';
            app.text65 = uilabel(app.Panel_2);
            app.text65.Tag = 'text65';
            app.text65.Text = 'Stimulus:';
            app.Stimulus_type = uidropdown(app.Panel_2);
            app.Stimulus_type.Items = {'Contour crude', 'Contour fine ', 'Square Vs O crude', 'Square Vs O fine', 'unknown', 'XvsO', 'Two Task Contour', 'Cued Images', 'Distract Contour', 'Grating', 'Contour Density', 'BehaviorBox Practice'};
            app.Stimulus_type.Tag = 'Stimulus_type';
            app.Stimulus_type.Tooltip = 'choose stimulus';
            app.Stimulus_type.Value = 'Contour Density';
            app.checkbox19 = uicheckbox(app.Panel_2);
            app.checkbox19.Tag = 'Stimulus_FlashStim';
            app.checkbox19.Text = 'flash';
            app.checkbox19.Value = true;
            app.text121 = uilabel(app.Panel_2);
            app.text121.Tag = 'text121';
            app.text121.Text = 'freq';
            app.edit50 = uieditfield(app.Panel_2, 'text');
            app.edit50.Tag = 'Stimulus_FreqFlashInitial';
            app.edit50.Tooltip = 'Frequency of flashing stimulus';
            app.edit50.Value = '1';
            app.text122 = uilabel(app.Panel_2);
            app.text122.Tag = 'text122';
            app.text122.Text = 'reps';
            app.edit51 = uieditfield(app.Panel_2, 'text');
            app.edit51.Tag = 'Stimulus_RepFlashInitial';
            app.edit51.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.edit51.Value = '0';
            app.edit50_2 = uieditfield(app.Panel_2, 'text');
            app.edit50_2.Tag = 'Stimulus_FreqFlashAfter';
            app.edit50_2.Tooltip = 'Frequency of flashing stimulus';
            app.edit50_2.Value = '1';
            app.edit51_2 = uieditfield(app.Panel_2, 'text');
            app.edit51_2.Tag = 'Stimulus_RepFlashAfterC';
            app.edit51_2.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.edit51_2.Value = '0';
            app.edit51_3 = uieditfield(app.Panel_2, 'text');
            app.edit51_3.Tag = 'Stimulus_RepFlashAfterW';
            app.edit51_3.Tooltip = 'Number of times (repetitions) that stimulus is flashed';
            app.edit51_3.Value = '0';
            app.ContangletolEditFieldLabel = uilabel(app.Panel_2);
            app.ContangletolEditFieldLabel.Text = 'Cont. angle tol.';
            app.ContTol = uieditfield(app.Panel_2, 'numeric');
            app.ContTol.Tag = 'Stimulus_ContTol';
            app.ContTol.Tooltip = {'Stimuli are checked for accidental continuation and any angles below this threshold are adjusted'};
            app.ContTol.Value = 10;
            app.CorrectionoffsetEditFieldLabel = uilabel(app.Panel_2);
            app.CorrectionoffsetEditFieldLabel.Text = 'Correction offset';
            app.CorrectAngleAdj = uieditfield(app.Panel_2, 'numeric');
            app.CorrectAngleAdj.Tag = 'Stimulus_CorrectAngleAdj';
            app.CorrectAngleAdj.Tooltip = {'When correcting the randomly oriented segments, lines are offset by a random angle between this and the tolerance.'};
            app.CorrectAngleAdj.Value = 45;
            app.StimulusvariablesPanel = uipanel(app.SettingsTab);
            app.StimulusvariablesPanel.Title = 'Stimulus variables';
            app.edit21 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit21.Tag = 'Stimulus_size_y';
            app.edit21.Tooltip = {'Height in pixels'};
            app.edit21.Value = '10';
            app.edit20 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit20.Tag = 'Stimulus_size_x';
            app.edit20.Tooltip = {'Width in pixels'};
            app.edit20.Value = '10';
            app.edit19 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit19.Tag = 'Stimulus_position_x';
            app.edit19.Tooltip = {'X position in pixels'};
            app.edit19.Value = '1';
            app.edit17 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit17.Tag = 'Stimulus_position_y';
            app.edit17.Tooltip = {'Y position in pixels'};
            app.edit17.Value = '1';
            app.text72 = uilabel(app.StimulusvariablesPanel);
            app.text72.Tag = 'text72';
            app.text72.Text = 'com:';
            app.edit22 = uieditfield(app.StimulusvariablesPanel, 'text');
            app.edit22.Tag = 'Arduino_Com';
            app.edit22.Tooltip = 'com port to use for arduino';
            app.BoxEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.BoxEditFieldLabel.Text = 'Box #:';
            app.BoxNum = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.BoxNum.Tag = 'BoxNum';
            app.BoxNum.Tooltip = {'Put the Box/Monitor number in this spot. Each mouse should always use the same box.'};
            app.SegmentlengthEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentlengthEditFieldLabel.Text = 'Segment length';
            app.SegmentlengthEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SegmentlengthEditField.Tag = 'Stimulus_SegLength';
            app.SegmentlengthEditField.Value = 13;
            app.SegmentSpacingEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentSpacingEditFieldLabel.Text = 'Segment Spacing';
            app.SegmentSpacingEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SegmentSpacingEditField.Tag = 'Stimulus_SegSpacing';
            app.SegmentSpacingEditField.Value = 13;
            app.SpotlightbrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SpotlightbrightnessEditFieldLabel.Text = 'Spotlight brightness';
            app.SpotlightbrightnessEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SpotlightbrightnessEditField.Limits = [0 1];
            app.SpotlightbrightnessEditField.Tag = 'Stimulus_SpotlightColor';
            app.SpotlightbrightnessEditField.Tooltip = {'0 is black and 1 is white'};
            app.SpotlightbrightnessEditField.Value = 0.2;
            app.BetweenSpotlight_textlabel = uilabel(app.StimulusvariablesPanel);
            app.BetweenSpotlight_textlabel.Tooltip = {'Percentage of the screen space that is left blank between each spotlight'};
            app.BetweenSpotlight_textlabel.Text = 'Between spotlight (%)';
            app.BetweenSpotlight = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.BetweenSpotlight.Limits = [0 99];
            app.BetweenSpotlight.Tag = 'Stimulus_BetweenSpotlight';
            app.BetweenSpotlight.Tooltip = {'Percentage of the screen space that is left blank between each spotlight. 33% is the default for the nose poke, use a smaller value for the wheel.'};
            app.LinebrightnessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.LinebrightnessEditFieldLabel.Text = 'Line brightness';
            app.LinebrightnessEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.LinebrightnessEditField.Limits = [0 1];
            app.LinebrightnessEditField.Tag = 'Stimulus_LineColor';
            app.LinebrightnessEditField.Tooltip = {'0 is black and 1 is white'};
            app.LinebrightnessEditField.Value = 0.7;
            app.EditField_5Label_2 = uilabel(app.StimulusvariablesPanel);
            app.EditField_5Label_2.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.EditField_5Label_2.Text = 'Flash Color';
            app.FlashColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.FlashColor.Limits = [0 1];
            app.FlashColor.Tag = 'Stimulus_FlashColor';
            app.FlashColor.Tooltip = {'The stimulus will alternate between this color and the regular brightness.'};
            app.BackgroundEditFieldLabel_2 = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel_2.Text = 'Dim Color';
            app.Stimulus_DimColor = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.Stimulus_DimColor.Limits = [0 1];
            app.Stimulus_DimColor.Tag = 'Stimulus_DimColor';
            app.Stimulus_DimColor.Tooltip = {'0 is black and 1 is white'};
            app.Stimulus_DimColor.Value = 0.5;
            app.BackgroundEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.BackgroundEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.BackgroundEditField.Limits = [0 1];
            app.BackgroundEditField.Tag = 'Stimulus_BackgroundColor';
            app.BackgroundEditField.Tooltip = {'0 is black and 1 is white'};
            app.BackgroundEditField.Value = 0.5;
            app.ReadyCuecolorLabel = uilabel(app.StimulusvariablesPanel);
            app.ReadyCuecolorLabel.Text = 'ReadyCue color';
            app.EditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.Tag = 'ReadyCue_Color';
            app.EditField.Value = 0.7;
            app.EditField_2Label = uilabel(app.StimulusvariablesPanel);
            app.EditField_2Label.Text = 'ReadyCue size';
            app.EditField_2 = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.EditField_2.Limits = [1 Inf];
            app.EditField_2.Tag = 'ReadyCue_Size';
            app.EditField_2.Value = 12;
            app.SegmentthicknessEditFieldLabel = uilabel(app.StimulusvariablesPanel);
            app.SegmentthicknessEditFieldLabel.Text = 'Segment thickness';
            app.SegmentthicknessEditField = uieditfield(app.StimulusvariablesPanel, 'numeric');
            app.SegmentthicknessEditField.Tag = 'Stimulus_SegThick';
            app.SegmentthicknessEditField.Value = 13;
            app.InputControlPanel = uipanel(app.SettingsTab);
            app.InputControlPanel.Title = 'Input Control';
            app.TurnMagEditFieldLabel = uilabel(app.InputControlPanel);
            app.TurnMagEditFieldLabel.Text = 'Turn Mag.';
            app.TurnMagEditField = uieditfield(app.InputControlPanel, 'numeric');
            app.TurnMagEditField.Tag = 'TurnMag';
            app.TurnMagEditField.Value = 1000;
            app.RoundupchoiceCheckBox = uicheckbox(app.InputControlPanel);
            app.RoundupchoiceCheckBox.Tag = 'RoundUp';
            app.RoundupchoiceCheckBox.Tooltip = {'Choose to accept the mouse''s choice at timeout if they turn the wheel *almost* to the choice.'};
            app.RoundupchoiceCheckBox.Text = 'Round up choice';
            app.RoundUpEditFieldLabel = uilabel(app.InputControlPanel);
            app.RoundUpEditFieldLabel.Text = 'Round Up (%)';
            app.RoundUpEditField = uieditfield(app.InputControlPanel, 'numeric');
            app.RoundUpEditField.Limits = [0 110];
            app.RoundUpEditField.Tag = 'RoundUpVal';
            app.RoundUpEditField.Tooltip = {'Choose the percentage threshold to accept the mouse''s choice if they do not turn the wheel fully.'};
            app.RoundUpEditField.Value = 95;
            app.FinishlineCheckBox = uicheckbox(app.InputControlPanel);
            app.FinishlineCheckBox.Tag = 'Stimulus_FinishLine';
            app.FinishlineCheckBox.Tooltip = {'DIsplay a marker that will indicate how far the mouse has to turn the wheel to input a choice. Based on stimulus variables.'};
            app.FinishlineCheckBox.Text = 'Finish line?';
            app.IntertrialMalCheckBox = uicheckbox(app.InputControlPanel);
            app.IntertrialMalCheckBox.Tag = 'IntertrialMalCancel';
            app.IntertrialMalCheckBox.Tooltip = {'Check this box to end the current trial if the mouse pokes left or right when the ready cue is up. Only a center poke will start the next trial.'};
            app.IntertrialMalCheckBox.Text = 'Intertrial Mal';
            app.IntertrialMalCheckBox.Value = true;
            app.EditField_5 = uieditfield(app.InputControlPanel, 'numeric');
            app.EditField_5.Tag = 'IntertrialMalSec';
            app.EditField_5.Tooltip = {'If the box is checked, a new trial will not begin until this many seconds after the mouse last poked Left or Right during the intertrial period. Once a new trial does begin, it will cancel if the mouse pokes anything but the center.'};
            app.EditField_5.Value = 1;
            app.DuringTrialEditFieldLabel = uilabel(app.InputControlPanel);
            app.DuringTrialEditFieldLabel.Text = 'Input delay';
            app.InputDelayStart = uieditfield(app.InputControlPanel, 'numeric');
            app.InputDelayStart.Limits = [0 2];
            app.InputDelayStart.Tag = 'Input_Delay_Start';
            app.InputDelayStart.Tooltip = {'How long the mouse needs to hold their choice before it is accepted. Prevents mice from mindlessly choosing. Less than a second is best.'};
            app.InputDelayStart.Value = 0.5;
            app.EditField_6Label = uilabel(app.InputControlPanel);
            app.EditField_6Label.Text = 'Hold Still Thresh';
            app.EditField_6 = uieditfield(app.InputControlPanel, 'numeric');
            app.EditField_6.Limits = [0 500];
            app.EditField_6.Tag = 'Hold_Still_Thresh';
            app.EditField_6.Tooltip = {'Speed mouse must keep wheel under during "Hold Still" interval before trial.'};
            app.EditField_6.Value = 10;
            app.InputDelayRespond = uieditfield(app.InputControlPanel, 'numeric');
            app.InputDelayRespond.Limits = [0 2];
            app.InputDelayRespond.Tag = 'Input_Delay_Respond';
            app.InputDelayRespond.Tooltip = {'How long the mouse needs to hold their choice before it is accepted. Prevents mice from mindlessly choosing. Less than a second is best.'};
            app.InputDelayRespond.Value = 0.1;
            app.EasyLvList = uieditfield(app.SettingsTab, 'text');
            app.EasyLvList.Tag = 'Level_EasyLvList';
            app.EasyLvList.Value = '1';
            app.HardLvList = uieditfield(app.SettingsTab, 'text');
            app.HardLvList.Tag = 'Level_HardLvList';
            app.HardLvList.Value = '2:6';
            app.HighLvProbEditFieldLabel = uilabel(app.SettingsTab);
            app.HighLvProbEditFieldLabel.Text = 'High Lv. Prob';
            app.HighLvProb = uieditfield(app.SettingsTab, 'numeric');
            app.HighLvProb.Limits = [0 1];
            app.HighLvProb.ValueChangedFcn = createCallbackFcn(app, @HighLvProbValueChanged, true);
            app.HighLvProb.Tag = 'Level_HighLvProb';
            app.HighLvProb.Value = 0.8;
            app.EasyLvProbLabel = uilabel(app.SettingsTab);
            app.EasyLvProbLabel.Text = 'Easy Lv. Prob';
            app.EasyLvProb = uieditfield(app.SettingsTab, 'numeric');
            app.EasyLvProb.Limits = [0 1];
            app.EasyLvProb.ValueChangedFcn = createCallbackFcn(app, @EasyLvProbValueChanged, true);
            app.EasyLvProb.Tag = 'Level_EasyLvProb';
            app.EasyLvProb.Value = 0.2;
            app.TemporaryTab = uitab(app.TabGroup);
            app.TemporaryTab.Title = 'Temporary';
            app.uipanel1_2 = uipanel(app.TemporaryTab);
            app.uipanel1_2.Title = 'Level & Side';
            app.uipanel1_2.Tag = 'uipanel1';
            app.DistractorsSpinnerLabel_2 = uilabel(app.uipanel1_2);
            app.DistractorsSpinnerLabel_2.Text = 'Level';
            app.DistractorsSpinner_2 = uispinner(app.uipanel1_2);
            app.DistractorsSpinner_2.Limits = [1 20];
            app.DistractorsSpinner_2.Tag = 'Starting_opacity_Temp';
            app.DistractorsSpinner_2.Value = 1;
            app.checkbox22_2 = uicheckbox(app.uipanel1_2);
            app.checkbox22_2.Tag = 'EasyTrials_Temp';
            app.checkbox22_2.Tooltip = 'Defines if easier trials should be shown or not';
            app.checkbox22_2.Text = 'Easy %';
            app.checkbox22_2.Value = true;
            app.prob_list_2 = uitextarea(app.uipanel1_2);
            app.prob_list_2.Tag = 'prob_list_Temp';
            app.uipanel3_2 = uipanel(app.TemporaryTab);
            app.uipanel3_2.Title = 'Trial Intervals';
            app.uipanel3_2.Tag = 'uipanel3';
            app.checkbox4_2 = uicheckbox(app.uipanel3_2);
            app.checkbox4_2.Tag = 'Input_ignored_Temp';
            app.checkbox4_2.Text = 'Input ignored';
            app.Pokes_ignored_time_2 = uieditfield(app.uipanel3_2, 'text');
            app.Pokes_ignored_time_2.Tag = 'Pokes_ignored_time_Temp';
            app.Pokes_ignored_time_2.Tooltip = 'ignore all input for how many seconds after trial begin';
            app.Pokes_ignored_time_2.Value = '2';
            app.text8_2 = uilabel(app.uipanel3_2);
            app.text8_2.Tag = 'text8';
            app.text8_2.Text = 'Penalty(s)';
            app.edit6_2 = uieditfield(app.uipanel3_2, 'text');
            app.edit6_2.Tag = 'Penalty_time_Temp';
            app.edit6_2.Tooltip = 'penalty delay in seconds';
            app.edit6_2.Value = '1';
            app.text10_2 = uilabel(app.uipanel3_2);
            app.text10_2.Tag = 'text10';
            app.text10_2.Text = 'Intertrial (s)';
            app.edit8_2 = uieditfield(app.uipanel3_2, 'text');
            app.edit8_2.Tag = 'Intertrial_time_Temp';
            app.edit8_2.Tooltip = 'Interval between each trial in seconds';
            app.edit8_2.Value = '1';
            app.EditField_5Label_3 = uilabel(app.uipanel3_2);
            app.EditField_5Label_3.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.EditField_5Label_3.Text = 'Hold still (s):';
            app.HoldStill_2 = uieditfield(app.uipanel3_2, 'numeric');
            app.HoldStill_2.Limits = [0 10];
            app.HoldStill_2.Tag = 'HoldStill_Temp';
            app.HoldStill_2.Tooltip = {'Hold the wheel still for this many seconds before a new trial starts. Timer will reset if the will is moved during this time.'};
            app.checkbox3_2 = uicheckbox(app.uipanel3_2);
            app.checkbox3_2.Tag = 'Repeat_wrong_Temp';
            app.checkbox3_2.Tooltip = 'repeat the same side if last one was wrong';
            app.checkbox3_2.Text = 'repeat wrong';
            app.InputControlPanel_2 = uipanel(app.TemporaryTab);
            app.InputControlPanel_2.Title = 'Input Control';
            app.IntertrialMalCheckBox_2 = uicheckbox(app.InputControlPanel_2);
            app.IntertrialMalCheckBox_2.Tag = 'IntertrialMalCancel_Temp';
            app.IntertrialMalCheckBox_2.Tooltip = {'Check this box to end the current trial if the mouse pokes left or right when the ready cue is up. Only a center poke will start the next trial.'};
            app.IntertrialMalCheckBox_2.Text = 'Intertrial Mal';
            app.IntertrialMalCheckBox_2.Value = true;
            app.EditField_9 = uieditfield(app.InputControlPanel_2, 'numeric');
            app.EditField_9.Tag = 'IntertrialMalSec_Temp';
            app.EditField_9.Tooltip = {'If the box is checked, a new trial will not begin until this many seconds after the mouse last poked Left or Right during the intertrial period. Once a new trial does begin, it will cancel if the mouse pokes anything but the center.'};
            app.EditField_9.Value = 1;
            app.EditField_6Label_2 = uilabel(app.InputControlPanel_2);
            app.EditField_6Label_2.Text = 'Hold Still Thresh';
            app.EditField_10 = uieditfield(app.InputControlPanel_2, 'numeric');
            app.EditField_10.Limits = [0 500];
            app.EditField_10.Tag = 'Hold_Still_Thresh_Temp';
            app.EditField_10.Tooltip = {'Speed mouse must keep wheel under during "Hold Still" interval before trial.'};
            app.EditField_10.Value = 10;
            app.ExpireAfterButtonGroup = uibuttongroup(app.TemporaryTab);
            app.ExpireAfterButtonGroup.Title = 'Expire After';
            app.PerformanceButton = uiradiobutton(app.ExpireAfterButtonGroup);
            app.PerformanceButton.Tag = 'PerformanceThreshold_Temp';
            app.PerformanceButton.Tooltip = {'Temporary settings will persist until performance at Level 1 crosses the threshold'};
            app.PerformanceButton.Text = 'Performance';
            app.TrialNumberButton = uiradiobutton(app.ExpireAfterButtonGroup);
            app.TrialNumberButton.Tag = 'TrialNumber_Temp';
            app.TrialNumberButton.Text = 'Trial Number';
            app.PerformanceThreshold = uieditfield(app.ExpireAfterButtonGroup, 'numeric');
            app.PerformanceThreshold.Tag = 'PerfThresh_Temp';
            app.TrialsDuration = uieditfield(app.ExpireAfterButtonGroup, 'numeric');
            app.TrialsDuration.Tag = 'TrialCount_Temp';
            app.TrialsRemainingLabel = uilabel(app.ExpireAfterButtonGroup);
            app.TrialsRemainingLabel.Text = '_ Trials Remaining';
            app.OffButton = uiradiobutton(app.ExpireAfterButtonGroup);
            app.OffButton.Tag = 'TempOff_Temp';
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.NotesTab = uitab(app.TabGroup);
            app.NotesTab.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.NotesTab.Title = 'Notes';
            app.NotesTab.Tag = 'INO';
            app.TextArea2 = uitextarea(app.NotesTab);
            app.TextArea2.Tag = 'NotesText';
            app.TextArea2.Tooltip = {'Write notes from today''s training session to be saved in the data file.'};
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.MsgBox = uitextarea(app.OutputTab);
            app.MsgBox.Tag = 'MsgBox';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.PerformanceTab.Tag = 'PerfTab';
            app.TimersTab = uitab(app.TabGroup);
            app.TimersTab.Title = 'Timers';
            app.AllTimeTab = uitab(app.TabGroup);
            app.AllTimeTab.Title = 'All Time';
            app.WeightWaterTab = uitab(app.TabGroup);
            app.WeightWaterTab.Title = 'Weight/Water';
            app.OtherUnusedTab = uitab(app.TabGroup);
            app.OtherUnusedTab.Title = 'Other / Unused';
            app.uipanel11 = uipanel(app.OtherUnusedTab);
            app.uipanel11.Title = 'Orientation Tuning';
            app.uipanel11.Tag = 'uipanel11';
            app.text79 = uilabel(app.uipanel11);
            app.text79.Tag = 'text79';
            app.text79.Text = 'Repeats:';
            app.text81 = uilabel(app.uipanel11);
            app.text81.Tag = 'text81';
            app.text81.Text = 'Duration:';
            app.text82 = uilabel(app.uipanel11);
            app.text82.Tag = 'text82';
            app.text82.Text = 'Orientations:';
            app.text83 = uilabel(app.uipanel11);
            app.text83.Tag = 'text83';
            app.text83.Text = 'Interval:';
            app.text84 = uilabel(app.uipanel11);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Bar size:';
            app.text85 = uilabel(app.uipanel11);
            app.text85.Tag = 'text85';
            app.text85.Text = 'Frequency:';
            app.pushbutton19 = uibutton(app.uipanel11, 'push');
            app.pushbutton19.ButtonPushedFcn = createCallbackFcn(app, @pushbutton19_Callback, true);
            app.pushbutton19.Tag = 'Orientation';
            app.pushbutton19.Tooltip = 'show orientation tuning only (no input required)';
            app.pushbutton19.Text = 'Orientation Tuning Only';
            app.edit25 = uieditfield(app.uipanel11, 'text');
            app.edit25.Tag = 'Ori_Repeats';
            app.edit25.Tooltip = 'how many repeats of all orientations';
            app.edit25.Value = '2';
            app.edit26 = uieditfield(app.uipanel11, 'text');
            app.edit26.Tag = 'Ori_Duration';
            app.edit26.Tooltip = 'how long to show each orientation';
            app.edit26.Value = '2';
            app.popupmenu4 = uidropdown(app.uipanel11);
            app.popupmenu4.Items = {'4', '8', '16'};
            app.popupmenu4.Tag = 'Ori_Orientations';
            app.popupmenu4.Tooltip = 'total number of equally spaced orientations';
            app.popupmenu4.Value = '8';
            app.edit28 = uieditfield(app.uipanel11, 'text');
            app.edit28.Tag = 'Ori_Interval';
            app.edit28.Tooltip = 'interval between each orientation';
            app.edit28.Value = '2';
            app.checkbox13 = uicheckbox(app.uipanel11);
            app.checkbox13.Tag = 'Ori_Random';
            app.checkbox13.Tooltip = 'randomize orientations';
            app.checkbox13.Text = 'random';
            app.edit29 = uieditfield(app.uipanel11, 'text');
            app.edit29.Tag = 'Ori_Bar_Size';
            app.edit29.Tooltip = 'size gratings';
            app.edit29.Value = '170';
            app.edit30 = uieditfield(app.uipanel11, 'text');
            app.edit30.Tag = 'Ori_Frequency';
            app.edit30.Tooltip = 'temporal frequency of grating';
            app.edit30.Value = '0.5';
            app.edit32 = uieditfield(app.uipanel11, 'text');
            app.edit32.Tag = 'Stimulus_Orient';
            app.edit32.Tooltip = {'Orientation of stimulus in degrees'};
            app.edit32.Value = '0';
            app.uipanel12 = uipanel(app.OtherUnusedTab);
            app.uipanel12.Title = 'Difficulty Adjust';
            app.uipanel12.Tag = 'uipanel12';
            app.GridLayout4 = uigridlayout(app.uipanel12);
            app.popupmenu9 = uidropdown(app.GridLayout4);
            app.popupmenu9.Items = {'Inc/dec after trials', 'Step up after trials', 'Random diff in range'};
            app.popupmenu9.Tag = 'DiffAdjustMethod';
            app.popupmenu9.Value = 'Inc/dec after trials';
            app.checkbox1 = uicheckbox(app.GridLayout4);
            app.checkbox1.Tag = 'Raise_bg_with_perf';
            app.checkbox1.Text = 'inc diff after trials:';
            app.edit23 = uieditfield(app.GridLayout4, 'text');
            app.edit23.Tag = 'RaiseDiffAfterBins';
            app.edit23.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit23.Value = '10';
            app.checkbox18 = uicheckbox(app.GridLayout4);
            app.checkbox18.Tag = 'Lower_bg_with_perf';
            app.checkbox18.Text = 'dec diff after trials:';
            app.edit35 = uieditfield(app.GridLayout4, 'text');
            app.edit35.Tag = 'LowerDiffAfterBins';
            app.edit35.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit35.Value = '10';
            app.text94 = uilabel(app.GridLayout4);
            app.text94.Tag = 'text94';
            app.text94.Text = 'inc thres:';
            app.edit37 = uieditfield(app.GridLayout4, 'text');
            app.edit37.Tag = 'RaiseThres';
            app.edit37.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit37.Value = '0.65';
            app.text95 = uilabel(app.GridLayout4);
            app.text95.Tag = 'text95';
            app.text95.Text = 'dec thres:';
            app.edit38 = uieditfield(app.GridLayout4, 'text');
            app.edit38.Tag = 'LowerThres';
            app.edit38.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit38.Value = '0.5';
            app.text96 = uilabel(app.GridLayout4);
            app.text96.Tag = 'text96';
            app.text96.Text = 'Adj Method:';
            app.text97 = uilabel(app.GridLayout4);
            app.text97.Tag = 'text97';
            app.text97.Text = 'step up after trials:';
            app.edit39 = uieditfield(app.GridLayout4, 'text');
            app.edit39.Tag = 'StepUpAfter';
            app.edit39.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit39.Value = '20';
            app.text98 = uilabel(app.GridLayout4);
            app.text98.Tag = 'text98';
            app.text98.Text = 'step:';
            app.edit40 = uieditfield(app.GridLayout4, 'text');
            app.edit40.Tag = 'RandomMax';
            app.edit40.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit40.Value = '1';
            app.text99 = uilabel(app.GridLayout4);
            app.text99.Tag = 'text99';
            app.text99.Text = 'random max:';
            app.edit41 = uieditfield(app.GridLayout4, 'text');
            app.edit41.Tag = 'RandomStep';
            app.edit41.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit41.Value = '0.1';
            app.text100 = uilabel(app.GridLayout4);
            app.text100.Tag = 'text100';
            app.text100.Text = 'random min:';
            app.edit42 = uieditfield(app.GridLayout4, 'text');
            app.edit42.Tag = 'RandomMin';
            app.edit42.Tooltip = 'adjust background after how many correct/wrong trials';
            app.edit42.Value = '0.4';
            app.text123 = uilabel(app.GridLayout4);
            app.text123.Tag = 'text123';
            app.text123.Text = 'sub bin size:';
            app.edit52 = uieditfield(app.GridLayout4, 'text');
            app.edit52.Tag = 'sub_bin_size';
            app.edit52.Tooltip = 'size of sub bin (to measure std threshold)';
            app.edit52.Value = '5';
            app.text124 = uilabel(app.GridLayout4);
            app.text124.Tag = 'text124';
            app.text124.Text = 'std thres:';
            app.edit53 = uieditfield(app.GridLayout4, 'text');
            app.edit53.Tag = 'stdthreshold';
            app.edit53.Tooltip = 'standard deviation threshold to change difficulty';
            app.edit53.Value = '0.5';
            app.uipanel8 = uipanel(app.OtherUnusedTab);
            app.uipanel8.Title = 'Input training';
            app.uipanel8.Tag = 'uipanel8';
            app.GridLayout3 = uigridlayout(app.uipanel8);
            app.checkbox6 = uicheckbox(app.GridLayout3);
            app.checkbox6.Tag = 'Show_two_bars';
            app.checkbox6.Tooltip = 'Show two bars for input training only';
            app.checkbox6.Text = 'show two training bars';
            app.checkbox6.Value = true;
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'Unlimited Rewards', 'Only Correct or Timeout', 'None', 'Go/No-Go Practice', 'Wheel Practice Early', 'Wheel Practice Late', 'Wheel Practice - No wrong choice', 'NosePoke Training 1', 'NosePoke Training 2', 'NosePoke Training 3', 'NosePoke Training 4', 'NosePoke Training 5'};
            app.DropDown.Tag = 'TrainingChoices';
            app.DropDown.Tooltip = {'Choose Special Rules for training new mice.'};
            app.DropDown.Value = 'None';
            app.FlashifwrongCheckBox = uicheckbox(app.GridLayout3);
            app.FlashifwrongCheckBox.Tag = 'Stimulus_TrainingFlashifWrong';
            app.FlashifwrongCheckBox.Text = 'Flash if wrong?';
            app.pushbutton3 = uibutton(app.GridLayout3, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'Train';
            app.pushbutton3.Tooltip = 'Start input training (either choice will get reward)';
            app.pushbutton3.Text = 'Training Mode';
            app.checkbox8 = uicheckbox(app.OtherUnusedTab);
            app.checkbox8.Tag = 'Ext_trigger';
            app.checkbox8.Tooltip = 'start experiment by an external trigger';
            app.checkbox8.Text = 'ext trigger';
            app.EquipmentPanel = uipanel(app.OtherUnusedTab);
            app.EquipmentPanel.Title = 'Equipment';
            app.OpenLButton = uibutton(app.EquipmentPanel, 'state');
            app.OpenLButton.ValueChangedFcn = createCallbackFcn(app, @OpenLButtonValueChanged, true);
            app.OpenLButton.Tag = 'OpenL';
            app.OpenLButton.Text = 'Open L';
            app.OpenRButton = uibutton(app.EquipmentPanel, 'state');
            app.OpenRButton.ValueChangedFcn = createCallbackFcn(app, @OpenRButtonValueChanged, true);
            app.OpenRButton.Tag = 'OpenR';
            app.OpenRButton.Text = 'Open R';
            app.OpenBothButton = uibutton(app.EquipmentPanel, 'state');
            app.OpenBothButton.ValueChangedFcn = createCallbackFcn(app, @OpenBothButtonValueChanged, true);
            app.OpenBothButton.Tag = 'OpenBoth';
            app.OpenBothButton.Text = 'Open Both';
            app.RESETALLButton_2 = uibutton(app.EquipmentPanel, 'state');
            app.RESETALLButton_2.ValueChangedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.RESETALLButton_2.Tag = 'ResetAll';
            app.RESETALLButton_2.Text = 'RESET ALL';
            app.LoadComputerSettingsButton = uibutton(app.EquipmentPanel, 'push');
            app.LoadComputerSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadComputerSettingsButtonPushed, true);
            app.LoadComputerSettingsButton.Tag = 'LoadCompButton';
            app.LoadComputerSettingsButton.Text = 'Load Computer Settings';
            app.SaveComputerSettingsButton = uibutton(app.EquipmentPanel, 'push');
            app.SaveComputerSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveComputerSettingsButtonPushed, true);
            app.SaveComputerSettingsButton.Tag = 'SaveCompButton';
            app.SaveComputerSettingsButton.Text = 'Save Computer Settings';
            app.ArduinosDropDownLabel = uilabel(app.OtherUnusedTab);
            app.ArduinosDropDownLabel.Text = 'Arduino(s):';
            app.ArduinosDropDown = uidropdown(app.OtherUnusedTab);
            app.ArduinosDropDown.Tag = 'ArduinosDropDown';
            app.Git = uibutton(app.figure1, 'push');
            app.Git.ButtonPushedFcn = createCallbackFcn(app, @GitButtonPushed, true);
            app.Git.Tag = 'Git';
            app.Git.Icon = fullfile(pathToMLAPP, 'imgs', 'git.png');
            app.Git.Tooltip = {'Update this App by pulling from stable Git repo'};
            app.Git.Text = '';
            app.Folder = uibutton(app.figure1, 'push');
            app.Folder.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.Folder.Tag = 'Folder';
            app.Folder.Icon = fullfile(pathToMLAPP, 'imgs', 'folder.png');
            app.Folder.Tooltip = {'Open mouse''s folder to see files'};
            app.Folder.Text = '';
            app.Button = uibutton(app.figure1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = 'VideoButton';
            app.Button.Icon = fullfile(pathToMLAPP, 'imgs', 'video.png');
            app.Button.Tooltip = {'Click to open up to 2 video feeds'};
            app.Button.Text = '';
            app.Curtain = uibutton(app.figure1, 'push');
            app.Curtain.ButtonPushedFcn = createCallbackFcn(app, @CurtainButtonPushed, true);
            app.Curtain.Tag = 'Curtain';
            app.Curtain.Icon = fullfile(pathToMLAPP, 'imgs', 'curtain.png');
            app.Curtain.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorBox_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @BehaviorBox_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end